classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        E4Button          matlab.ui.control.Button
        B3Button          matlab.ui.control.Button
        G3Button          matlab.ui.control.Button
        D3Button          matlab.ui.control.Button
        A2Button          matlab.ui.control.Button
        E2Button          matlab.ui.control.Button
        Gauge             matlab.ui.control.SemicircularGauge
        GuitarTunerLabel  matlab.ui.control.Label
        PitchLabel        matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function record(app, correct_tune)
            tune_rounded = round(correct_tune)
            app.Gauge.Limits = [tune_rounded-10, tune_rounded+10];
            while true
                recObj = audiorecorder(44100, 16, 1);
                get(recObj);
                recordblocking(recObj, 0.5);
                myRecording = getaudiodata(recObj);
                f0 = pitch(myRecording,44100);
                actual_tune = mode(f0); 
t                set(app.Gauge ,'Value',actual_tune)
                set(app.PitchLabel ,'Text',actual_tune + " Hz")
                if (actual_tune < correct_tune+0.75 && actual_tune > correct_tune-0.75)
                    set(app.PitchLabel ,'FontColor', [0 1 0])
                else
                    set(app.PitchLabel ,'FontColor', [1 0 0])
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: E4Button
        function E4(app, event)
            record(app, 329.628)
        end

        % Button pushed function: B3Button
        function B3(app, event)
            record(app, 246.942)
        end

        % Button pushed function: G3Button
        function G3(app, event)
            record(app, 195.998)
        end

        % Button pushed function: D3Button
        function D3(app, event)
            record(app, 146.832)
        end

        % Button pushed function: A2Button
        function A2(app, event)
            record(app, 110)
        end

        % Button pushed function: E2Button
        function E2(app, event)
            record(app, 82.407)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4, true);
            app.E4Button.Text = 'E4';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3, true);
            app.B3Button.Text = 'B3';
            app.G3Button = uibutton(app.UIFigure, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3, true);
            app.G3Button.Text = 'G3';
            app.D3Button = uibutton(app.UIFigure, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @D3, true);
            app.D3Button.Text = 'D3';
            app.A2Button = uibutton(app.UIFigure, 'push');
            app.A2Button.ButtonPushedFcn = createCallbackFcn(app, @A2, true);
            app.A2Button.Text = 'A2';
            app.E2Button = uibutton(app.UIFigure, 'push');
            app.E2Button.ButtonPushedFcn = createCallbackFcn(app, @E2, true);
            app.E2Button.Text = 'E2';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [-100 100];
            app.GuitarTunerLabel = uilabel(app.UIFigure);
            app.GuitarTunerLabel.Text = 'Guitar Tuner';
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end